classdef report_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        reversalButton  matlab.ui.control.Button
        computeButton   matlab.ui.control.Button
        loadButton      matlab.ui.control.Button
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function callback_load(app, event)
            img1=imread('./logo_csse.png');
            imshow(img1, 'Parent', app.UIAxes);
        end

        % Button pushed function: computeButton
        function callback_compute(app, event)
            img2=imread('./logo_csse.png');
            imgray = rgb2gray(img2);
            imgbw = imbinarize(imgray);
            imshow(imgbw, 'Parent', app.UIAxes2);
        end

        % Button pushed function: reversalButton
        function callback_reversal(app, event)
            img3=imread('./logo_csse.png');
            hsvImage = rgb2hsv(img3);
            hsvImage(:,:,1) = 1 - hsvImage(:,:,1);
            imgcolorrev = hsv2rgb(hsvImage);
            imgreverce = fliplr(imgcolorrev);
            imshow(imgreverce, 'Parent', app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @callback_load, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @callback_compute, true);
            app.computeButton.Text = 'compute';
            app.reversalButton = uibutton(app.UIFigure, 'push');
            app.reversalButton.ButtonPushedFcn = createCallbackFcn(app, @callback_reversal, true);
            app.reversalButton.Text = 'reversal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = report_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end